classdef TestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TESTINGADILabel      matlab.ui.control.Label
        LogInLabel           matlab.ui.control.Label
        ExamineeButton       matlab.ui.control.Button
        AdministratorButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AdministratorButton
        function AdministratorButtonPushed(app, event)
            % Create Administrator Page
            administratorFig = uifigure('Name', 'Administrator Page', 'Position', [100 100 800 600]);
            uilabel(administratorFig, 'Text', 'Enter Administrator Password:', 'Position', [200 350 400 50]);
            
            % Create Password Field
            passwordField = uieditfield(administratorFig, 'text');
            passwordField.Position = [200 300 400 50];
            
            % Create Submit Button
            submitButton = uibutton(administratorFig, 'push');
            submitButton.Position = [300 200 200 50];
            submitButton.Text = 'Submit';
            
            % Callback function for Submit Button
            submitButton.ButtonPushedFcn = @(btn, event) checkPassword(app, passwordField.Value, administratorFig);
        end
        
        % Function to check the password and open the next page
        function checkPassword(app, enteredPassword, administratorFig)
            correctPassword = '1';  % Replace with the actual password

            if strcmp(enteredPassword, correctPassword)
                % Close the password entry figure
                close(administratorFig);

                % Open the next page
                adminPage = uifigure('Name', 'Administrator Dashboard', 'Position', [200 100 800 600]);
                uilabel(adminPage, 'Text', 'Welcome to the Administrator Dashboard', 'Position', [200 500 400 50]);
           
         % Create Question Field
          questionLabel = uilabel(adminPage, 'Text', 'Enter Question for Examinee:', 'Position', [200 400 400 50]);
          questionField = uieditfield(adminPage, 'text');
          questionField.Position = [200 350 400 50];
  
                
        % Create Answers Field
        answersLabel = uilabel(adminPage, 'Text', 'Enter Answers comma separated (A-Z):', 'Position', [200 300 400 50]);
        answersField = uieditfield(adminPage, 'text');
        answersField.Position = [200 250 400 50];

        % Create Correct Answer Field
        correctAnswerLabel = uilabel(adminPage, 'Text', 'Enter Correct Answer (A-Z):', 'Position', [200 200 400 50]);
        correctAnswerField = uieditfield(adminPage, 'text');
        correctAnswerField.Position = [200 150 400 50];

                % Create Save Question Button
                saveQuestionButton = uibutton(adminPage, 'push');
                saveQuestionButton.Position = [200 25 400 50];
                saveQuestionButton.Text = 'Save Question';

          saveQuestionButton.ButtonPushedFcn = @(btn, event) saveQuestion(app, questionField.Value, answersField.Value, correctAnswerField.Value);  


            else
                % Display an error message
                uialert(administratorFig, 'Incorrect Password. Please try again.', 'Error');
            end
            % Function to save the question data
       
         function saveQuestion(app, question, answers, correctAnswer)
    questionData.question = question;
    questionData.answers = strsplit(answers, ',');
    questionData.correctAnswer = correctAnswer;
    setappdata(app.UIFigure, 'questionData', questionData);
    setappdata(app.UIFigure, 'question', question);
         end
         

        end

        % Button pushed function: ExamineeButton
        function ExamineeButtonPushed(app, event)
            examineeFig = uifigure('Name', 'Examinee Page', 'Position', [100 100 800 600]);
            uilabel(examineeFig, 'Text', 'Enter your name:', 'Position', [200 400 400 50]);

            % Create Name Field
            nameField = uieditfield(examineeFig, 'text');
            nameField.Position = [200 350 400 50];

            % Create Submit Button
            submitButton = uibutton(examineeFig, 'push');
            submitButton.Position = [300 250 200 50];
            submitButton.Text = 'Submit';

            % Callback function for Submit Button
            submitButton.ButtonPushedFcn = @(btn, event) showQuestion(app, nameField.Value, examineeFig);
        end

        % Function to show the question and the examinee's name
        function showQuestion(app, examineeName, examineeFig)
            % Close the name entry figure
            close(examineeFig);

            % Open the next page
            questionPage = uifigure('Name', 'Question Page', 'Position', [100 100 800 600]);
            questionData = getappdata(app.UIFigure, 'questionData');
            question = getappdata(app.UIFigure, 'question');


  if ~isempty(questionData) && ~isempty(question)
        uilabel(questionPage, 'Text', examineeName, 'Position', [10 550 400 50]);
        uilabel(questionPage, 'Text', question, 'Position', [200 450 400 50]);


       % Create buttons for each answer
       answers = questionData.answers; 
       numAnswers = length(answers);
        for i = 1:numAnswers
            answerButton = uibutton(questionPage, 'push');
            answerButton.Position = [200 400-(i*50) 400 50];
            answerButton.Text = [char(64+i) '. ' strtrim(answers{i})];  % Label buttons A, B, C, etc.
            answerButton.ButtonPushedFcn = @(btn, event) submitAnswer(app, examineeName, char(64+i), questionPage);
        end
    else
        uilabel(questionPage, 'Text', 'No question provided by the administrator.', 'Position', [200 300 400 50]);
    end
end

% Function to submit the answer and send it to the administrator
function submitAnswer(app, examineeName, selectedAnswer, questionPage)
    % Close the question page
    close(questionPage);

    % Get the correct answer from app data
    questionData = getappdata(app.UIFigure, 'questionData');
    correctAnswer = questionData.correctAnswer;

    % Determine if the answer is correct
    isCorrect = strcmp(selectedAnswer, correctAnswer);
    score = '0/1';
    if isCorrect
        score = '1/1';
    end

    % Open a new figure for the administrator to see the examinee's answer
    adminAnswerPage = uifigure('Name', 'Administrator Dashboard', 'Position', [100 100 800 600]);
    uilabel(adminAnswerPage, 'Text', ['Examinee Name: ' examineeName], 'Position', [200 500 400 50]);
    uilabel(adminAnswerPage, 'Text', ['Selected Answer: ' selectedAnswer], 'Position', [200 450 400 50]);
    uilabel(adminAnswerPage, 'Text', ['Correct Answer: ' correctAnswer], 'Position', [200 400 400 50]);
    uilabel(adminAnswerPage, 'Text', ['Score: ' score], 'Position', [200 350 400 50]);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AdministratorButton = uibutton(app.UIFigure, 'push');
            app.AdministratorButton.ButtonPushedFcn = createCallbackFcn(app, @AdministratorButtonPushed, true);
            app.AdministratorButton.Text = 'Administrator';
            app.ExamineeButton = uibutton(app.UIFigure, 'push');
            app.ExamineeButton.ButtonPushedFcn = createCallbackFcn(app, @ExamineeButtonPushed, true);
            app.ExamineeButton.Text = 'Examinee';
            app.LogInLabel = uilabel(app.UIFigure);
            app.LogInLabel.Text = 'Log In';
            app.TESTINGADILabel = uilabel(app.UIFigure);
            app.TESTINGADILabel.Text = 'TESTING ADI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end